classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Number_of_Cells_Text_2  matlab.ui.control.Label
        YFeatureLabel           matlab.ui.control.Label
        NextButton              matlab.ui.control.Button
        PreviousButton          matlab.ui.control.Button
        ModeSwitch              matlab.ui.control.Switch
        ImageDisp               matlab.ui.control.Image
        ExportData_Button       matlab.ui.control.Button
        Credit                  matlab.ui.control.Hyperlink
        XDropDown               matlab.ui.control.DropDown
        FeatureLabel            matlab.ui.control.Label
        YDropDown               matlab.ui.control.DropDown
        Number_of_Cells_Field   matlab.ui.control.NumericEditField
        Number_of_Cells_Text    matlab.ui.control.Label
        Segment_Button          matlab.ui.control.Button
        Help_Button             matlab.ui.control.Button
        Settings_Button         matlab.ui.control.Button
        UploadImagesButton      matlab.ui.control.Button
        ProgressBarAxes         matlab.ui.control.UIAxes
        HistogramAxes           matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = private)
    Images cell = {}; % Stores the uploaded image
    CurrentImageIndex double = 1;
    SegmentedImages = []; % Stores the segmented image
    net = []; % Stores the pre-trained CNN network
    Masks cell = {};
    Boxes cell = {};
    Labels cell = {};
    Scores cell = {};
    AnalysisData = [];
        Progress = 0; % Description
    end
    
    methods (Access = private)
        
        function [] = fixIMGDisplay(app, Image, ImageDisp) %function to display image file without labels properly
            %title(ImageAxes, []);
            %xlabel(ImageAxes, []);
            %ylabel(ImageAxes, []);
            %ImageAxes.XAxis.TickLabels = {};
            %ImageAxes.YAxis.TickLabels = {};
            %ImageAxes.PositionConstraint="outerposition";
            %ImageAxes.InnerPosition=ImageAxes.OuterPosition
    %         I = imshow(Image, 'Parent', app.ImageAxes, ...
    % 'XData', [1 app.ImageAxes.Position(3)], ...
    % 'YData', [1 app.ImageAxes.Position(4)]);
    %         box(ImageAxes,"on")
        if size(Image, 3)==1
            ImageDisp.ImageSource = repmat(Image, [1 1 3]);
        elseif size(Image, 3)==3
            ImageDisp.ImageSource = Image;
        end
       
        end
        function updateDisplayedImage(app)
            if isempty(app.SegmentedImages)
                return;
            end
    
            fixIMGDisplay(app, app.SegmentedImages{app.CurrentImageIndex}, app.ImageDisp);
            data = app.AnalysisData{app.CurrentImageIndex};
            app.Number_of_Cells_Field.Value = size(data, 1);
            app.Area_Field.Value = mean(data.Area);
            app.updatePlot();
        end


        function updatePlot(app)
       % Plotting depending on toggles
            if isempty(app.statsPerImage)
                return;
            end

            % Gather stats
            if  strcmp(app.ToggleCumulative.Value, 'On')
                % Cumulative across all images
                combinedStats = vertcat(app.statsPerImage{:});
            else
                % Just show first image's stats
                combinedStats = app.statsPerImage{1};
            end

            % Decide plot type
            if  strcmp(app.YDropDown.Value, 'Frequency')
                % Histogram view
                featureNameX = app.XDropDown.Value;
                featureValues = [combinedStats.(featureName)];
                histogram(app.HistogramAxes, featureValues);
            else
                % Scatter plot view
                featureX = app.XDropDown.Value;
                featureY = app.YDropDown.Value;

                xValues = [combinedStats.(featureX)];
                yValues = [combinedStats.(featureY)];

                scatter(app.HistogramAxes, xValues, yValues, 'filled');
            end
                xlabel(app.HistogramAxes, featureX);
                ylabel(app.HistogramAxes, featureY);
        end


        function UpdateProgress(app)
            barh(app.Progress, 'Parent',app.ProgressBarAxes,'EdgeColor','none','FaceColor',[0.4660 0.6740 0.1880], "BarWidth" ,20000)
            drawnow;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fixIMGDisplay(app, ones(520, 740)*0.9, app.ImageDisp);
            addpath(genpath('MRCNNsrc'));

            app.net = load('net'); % Load the network file
            app.net = app.net.net; % Extract the network object
            set(app.ProgressBarAxes,'visible','off');
            set(app.ProgressBarAxes,'xtick',[]);
            UpdateProgress(app);
            set(app.ProgressBarAxes, 'XLim', [0 1]);

        end

        % Callback function: ImageDisp, UploadImagesButton
        function UploadImage(app, event)
            [files, path] = uigetfile({'*.png;*.jpg;*.tif'}, 'Select Images', 'MultiSelect', 'on');
            if isequal(files, 0)
                errordlg('No images selected!', 'Error');
                return;
            end

            if ~iscell(files)
                files = {files};
            end

            app.Images = {};
            for k = 1:length(files)
                img = rescale(imread(fullfile(path, files{k})));
                [img, ~] = resizeImageandMask(img, [], [528, 704]);

                if size(img, 3) == 3
                    img = mean(img, 3);
                end
                app.Images{end+1} = img;
            end

            app.CurrentImageIndex = 1;
            app.SegmentedImages = {};
            app.Masks = {};
            app.Boxes = {};
            app.Labels = {};
            app.Scores = {};
            app.AnalysisData = {};
            fixIMGDisplay(app, app.Images{1}, app.ImageDisp);


        end

        % Button pushed function: Segment_Button
        function Segment(app, event)
        if isempty(app.Images)
        errordlg('Please upload an image first!', 'Error');
        return;
        end 

    % Add MRCNNsrc path (Make sure this path is correct)

    % Load pre-trained CNN model if not already loaded
    if isempty(app.net)
        try
        catch
            errordlg('Error loading CNN model. Check file path!', 'Error');
            return;
        end
    end


    % Perform cell segmentation
    try
        if ~class(app.Images)=='cell'
            app.Images{1} = app.Images; %makes sure all images have same format: cell, containing each image
            % move this logic to images being loaded later 
        end

        for i=[1:size(app.Images,2)] %for each image loaded in
        
         app.Progress = ((i-1)+ 0.05) / size(app.Images,2);
         UpdateProgress(app);
        
        [app.Masks{i} app.Labels{i} app.Scores{i} app.Boxes{i}] = segmentCells(app.net, rescale(app.Images{i}), "NumstrongestRegions",1000, MinSize=[4 4], MaxSize=[80 80],SelectStrongest=true, SegmentThreshold=0.5);

         app.Progress = ((i-1)+ 0.9) / size(app.Images,2);
         UpdateProgress(app);
            
        if isempty(app.Masks{i})
            app.SegmentedImages = app.Images{i};
        else
            app.SegmentedImages = insertObjectMask(app.Images{i}, app.Masks{i}, Color=lines(size(app.Masks{i}, 3)) );
        end
        % Display the segmented image with masks
        fixIMGDisplay(app, app.SegmentedImages, app.ImageDisp); drawnow;
        drawnow
        app.AnalysisData{i} = ExtractFeatures(app.Images{i}, app.Masks{i}, app.Boxes{i}, app.Scores{i}, 1);

        % Update the cell count field
        app.Number_of_Cells_Field.Value = size(app.AnalysisData{i}, 1);
        %app.Area_Field.Value = mean(app.AnalysisData.Area);
        app.Progress = i / size(app.Images,2);
        UpdateProgress(app);
        

        end
    
    catch ME
        errordlg(['Segmentation error: ', ME.message], 'Error');
    end

        
        end

        % Value changed function: XDropDown
        function PlotHistogram(app, event)
            value = app.XDropDown.Value;
            if isempty(app.AnalysisData)
                return;
            end
            % Extract the selected feature's values
            featureData = app.AnalysisData.(value);
            
            % Plot histogram
            histogram(featureData, 'Parent',app.HistogramAxes);
            xlabel(app.HistogramAxes, value);
            ylabel(app.HistogramAxes, 'Frequency');

        end

        % Callback function
        function PreviousButtonPushed(app, event)
              if app.CurrentImageIndex > 1
                app.CurrentImageIndex = app.CurrentImageIndex - 1;
                app.updateDisplayedImage();
              end
        
        end

        % Callback function
        function NextButtonPushed(app, event)
           if app.CurrentImageIndex < length(app.SegmentedImages)
                app.CurrentImageIndex = app.CurrentImageIndex + 1;
                app.updateDisplayedImage();
           end
        
        end

        % Callback function
        function ToggleSwitchValueChanged(app, event)
            app.updatePlot();
        end

        % Callback function
        function XAxisDropdownValueChanged(app, event)
            app.updatePlot();
        end

        % Callback function
        function YAxisDropdownValueChanged(app, event)
            app.updatePlot();
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'turbo');
            app.UIFigure.Name = 'MATLAB App';
            app.HistogramAxes = uiaxes(app.UIFigure);
            ylabel(app.HistogramAxes, 'Frequency')
            colormap(app.HistogramAxes, 'turbo')
            app.ProgressBarAxes = uiaxes(app.UIFigure);
            app.UploadImagesButton = uibutton(app.UIFigure, 'push');
            app.UploadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImage, true);
            app.UploadImagesButton.Text = 'Select Images';
            app.Settings_Button = uibutton(app.UIFigure, 'push');
            app.Settings_Button.Text = 'Settings (icon here)';
            app.Settings_Button.Icon = fullfile(pathToMLAPP, 'download.png');
            app.Help_Button = uibutton(app.UIFigure, 'push');
            app.Help_Button.Text = '?';
            app.Segment_Button = uibutton(app.UIFigure, 'push');
            app.Segment_Button.ButtonPushedFcn = createCallbackFcn(app, @Segment, true);
            app.Segment_Button.Text = 'Segment';
            app.Number_of_Cells_Text = uilabel(app.UIFigure);
            app.Number_of_Cells_Text.Text = 'Number of Cells ';
            app.Number_of_Cells_Field = uieditfield(app.UIFigure, 'numeric');
            app.Number_of_Cells_Field.Limits = [0 Inf];
            app.Number_of_Cells_Field.RoundFractionalValues = 'on';
            app.Number_of_Cells_Field.AllowEmpty = 'on';
            app.Number_of_Cells_Field.Editable = 'off';
            app.YDropDown = uidropdown(app.UIFigure);
            app.YDropDown.Items = {'Frequency', 'Area', 'Perimeter', 'STDeviation', 'AspectRatio', 'Eccentricity', 'Circularity', 'Solidity', 'MaxDiameter', 'Kurtosis'};
            app.YDropDown.Value = 'Area';
            app.FeatureLabel = uilabel(app.UIFigure);
            app.FeatureLabel.Text = 'X ';
            app.XDropDown = uidropdown(app.UIFigure);
            app.XDropDown.Items = {'Area', 'Perimeter', 'STDeviation', 'AspectRatio', 'Eccentricity', 'Circularity', 'Solidity', 'MaxDiameter', 'Kurtosis'};
            app.XDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotHistogram, true);
            app.XDropDown.Value = 'Area';
            app.Credit = uihyperlink(app.UIFigure);
            app.Credit.URL = 'https://github.com/TechAvi-eng/4th-Year-Image-Processing-Project';
            app.Credit.Text = {'GitHub'; ''};
            app.ExportData_Button = uibutton(app.UIFigure, 'push');
            app.ExportData_Button.Text = 'Export [icon]';
            app.ExportData_Button.Icon = fullfile(pathToMLAPP, 'export-icon-2048x2048-g2mr2v94.png');
            app.ImageDisp = uiimage(app.UIFigure);
            app.ImageDisp.ImageClickedFcn = createCallbackFcn(app, @UploadImage, true);
            app.ModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.ModeSwitch.Items = {'All Images', 'Individual Images'};
            app.ModeSwitch.Value = 'All Images';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.Text = 'Next';
            app.YFeatureLabel = uilabel(app.UIFigure);
            app.YFeatureLabel.Text = 'Y';
            app.Number_of_Cells_Text_2 = uilabel(app.UIFigure);
            app.Number_of_Cells_Text_2.Text = 'Plot Features:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end